classdef flys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        load_data_panel                 matlab.ui.container.Panel
        load_data_tab_group             matlab.ui.container.TabGroup
        stacksTab                       matlab.ui.container.Tab
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Button3                         matlab.ui.control.ToggleButton
        Button2                         matlab.ui.control.ToggleButton
        Button                          matlab.ui.control.ToggleButton
        load_stack_button               matlab.ui.control.Button
        natural_sort_check_box          matlab.ui.control.CheckBox
        stack_compute_pool_list_box     matlab.ui.control.ListBox
        stack_compute_pool_panel        matlab.ui.container.Panel
        parent_path_edit_field          matlab.ui.control.EditField
        parent_path_edit_field_label    matlab.ui.control.Label
        parent_path_button              matlab.ui.control.Button
        date_edit_field                 matlab.ui.control.EditField
        date_edit_field_label           matlab.ui.control.Label
        fly_edit_field                  matlab.ui.control.EditField
        fly_edit_field_label            matlab.ui.control.Label
        trial_edit_field                matlab.ui.control.EditField
        trial_edit_field_label          matlab.ui.control.Label
        suffix_drop_down                matlab.ui.control.DropDown
        suffix_drop_down_label          matlab.ui.control.Label
        matching_style_drop_down        matlab.ui.control.DropDown
        matching_style_drop_down_label  matlab.ui.control.Label
        browse_files_button             matlab.ui.control.Button
        add_selected_button             matlab.ui.control.Button
        list_matches_button             matlab.ui.control.Button
        matches_list_box                matlab.ui.control.ListBox
        CenterPanel                     matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        outputLabel_2                   matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        cw_text_area                    matlab.ui.control.TextArea
        outputLabel                     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        pathenv = getpathenv();
        startingdir = getpathenv();
        stack_compute_pool_list_box_items_original = [];
        valid_fnextensions = {'*.mat';'*.tif'}
        valid_fnsuffixes = {'raw', 'cmrg', 'cmrg_dcdn', 'bksb_cmrg', 'bksb_cmrg_dcdn', 'bksb_cmrg_dcdn_nosn'}
    end
    
    methods (Access = private)
        
        function enforce_singleton(app) %shutdown other instances of this app
            figures = findall(groot, '-depth', 1, 'Type', 'Figure'); %all figures from other instances 
            for k = 1:numel(figures)
                fig = figures(k);
                if isvalid(fig) && isprop(fig, 'RunningAppInstance') %only valid figures with a 'RunningAppInstance' (from AppDesigner)
                    other = fig.RunningAppInstance;
                    if isa(other, class(app)) && ~isequal(app, other) %Make sure the figure's app is the same class; don't delete ourself 
                        delete(fig.RunningAppInstance); %delete other instance and associated figures
                    end
                end
            end
        end

        function setPromptFcn(app,cw_text_area,eventData,newPrompt)
            persistent inProgress % Prevent overlapping reentry due to prompt replacement
            if isempty(inProgress)
                inProgress = 1;  %#ok unused; don't put a breakpoint here it will enter endless loop and crash
            else
                return;
            end
            try % *** Prompt modification code goes here ***
                cwText = char(cw_text_area.getText);
                app.cw_text_area.Value = cwText;
                scroll(app.cw_text_area, 'bottom')
                pause(0.02); % force prompt-change callback to fizzle-out...
            catch% ignore errors...
            end
            inProgress = []; %Enable new callbacks now that the prompt has been modified
        end  

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, username)
            arguments
                app
                username = []
            end
            enforce_singleton(app) %shutdown other instances of this app
            if isempty(username)
                app.parent_path_edit_field.Value = '';
            elseif strcmp(username, 'carl')
                app.startingdir = '/Users/wienecke/stacks';
                app.parent_path_edit_field.Value = app.startingdir;
            end
            app.suffix_drop_down.Items = cat(2, '*', app.valid_fnsuffixes);
            app.matches_list_box.Items = {''};
            app.stack_compute_pool_list_box.Items = {''};
            app.date_edit_field.Value = '*';
            app.fly_edit_field.Value = '*';
            app.trial_edit_field.Value = '*';
            app.matching_style_drop_down.Items = {'any', 'each'};

            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                cw_text_area = jCmdWin.getComponent(0).getViewport.getView;
                set(cw_text_area, 'CaretUpdateCallback', @app.setPromptFcn)
            catch
                warndlg('fatal error');
            end

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(app.UIFigure.CurrentObject.Tag, 'stack_compute_pool_list_box')
                if strcmp(key, 'backspace') %delete selected from list box 
                    app.UIFigure.CurrentObject.Items = app.UIFigure.CurrentObject.Items(~contains(app.UIFigure.CurrentObject.Items, app.UIFigure.CurrentObject.Value ));
                end
            end
        end

        % Button pushed function: parent_path_button
        function parent_path_button_pushed(app, event)
            app.parent_path_edit_field.Value = uigetdir(app.startingdir, 'select a directory containing all recordings you want to analyze');
        end

        % Button pushed function: list_matches_button
        function list_matches_button_pressed(app, event)
            ui.mn.pthparent_o2 = '';
            pthstacks = find_preprocessed_files( ...
                pthparent_local=app.parent_path_edit_field.Value, ...
                pthparent_o2=ui.mn.pthparent_o2, ...
                valid_fnsuffixes=app.valid_fnsuffixes, ...
                recdate=app.date_edit_field.Value, ...
                fly=app.fly_edit_field.Value, ...
                trial=app.trial_edit_field.Value, ...
                suffix=app.suffix_drop_down.Value, ...
                fnspec_matching_style=app.matching_style_drop_down.Value);
            app.matches_list_box.Items = pthstacks;
            sprintf(sprintf('%s, \n', app.matches_list_box.Items{:})) 
        end

        % Button pushed function: add_selected_button
        function add_selected_button_pushed(app, event)
            selected = app.matches_list_box.Value;
            if isempty(cell2mat(app.stack_compute_pool_list_box.Items))
                app.stack_compute_pool_list_box.Items = selected;
                app.stack_compute_pool_list_box_items_original = selected;
            else
                app.stack_compute_pool_list_box.Items(end+1:end+numel(selected)) = selected;
                app.stack_compute_pool_list_box_items_original(end+1:end+numel(selected)) = selected;
            end
            app.stack_compute_pool_list_box.Items = unique(app.stack_compute_pool_list_box.Items, 'stable');
            app.stack_compute_pool_list_box_items_original = unique(app.stack_compute_pool_list_box_items_original, 'stable');
        end

        % Button pushed function: browse_files_button
        function browse_files_button_pushed(app, event)
            multiselectmode = 'on';
            selected = [];
            for k = 1:numel(app.valid_fnextensions)
                getfile_filter = strcat(app.parent_path_edit_field.Value, filesep, '**', filesep, app.valid_fnextensions(k));
                msg = sprintf("select one or more " + app.valid_fnextensions(k) + ' stacks (each extension must be done separately, unfortunately)');
                [fntmp, fldrtmp] = uigetfile(getfile_filter, msg, app.startingdir, 'MultiSelect', multiselectmode);
                tmp = fullfile(fldrtmp, fntmp);
                if ~iscell(tmp)
                    tmp = {tmp};
                end
                selected = cat(2, selected, tmp);
            end
            xflt = contains(selected, app.valid_fnsuffixes);
            if any(xflt)
                selected = selected(xflt);
            else
                selected = 'INVALID';
            end
            selected = unique(selected, 'stable');

            %INSERT FUNCTION TO REMOVE DUPES ACROSS TIF AND MAT or FLYG SCOPA

            if isempty(cell2mat(app.stack_compute_pool_list_box.Items))
                app.stack_compute_pool_list_box.Items = selected;
                app.stack_compute_pool_list_box_items_original = selected;
            else
                app.stack_compute_pool_list_box.Items(end+1:end+numel(selected)) = selected;
                app.stack_compute_pool_list_box_items_original(end+1:end+numel(selected)) = selected;
            end
            app.stack_compute_pool_list_box.Items = unique(app.stack_compute_pool_list_box.Items, 'stable');
            app.stack_compute_pool_list_box_items_original = unique(app.stack_compute_pool_list_box_items_original, 'stable');
        end

        % Value changed function: natural_sort_check_box
        function natural_sort_check_box_value_changed(app, event)
            if ~isempty(cell2mat(app.stack_compute_pool_list_box.Items))
                if app.natural_sort_check_box.Value
                    if isempty(app.stack_compute_pool_list_box_items_original)
                        app.stack_compute_pool_list_box_items_original = app.stack_compute_pool_list_box.Items;
                    end
                    app.stack_compute_pool_list_box.Items = natsortfiles(app.stack_compute_pool_list_box.Items);
                else
                    app.stack_compute_pool_list_box.Items = app.stack_compute_pool_list_box_items_original;
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {691, 691, 691};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.load_data_panel.Layout.Row = 2;
                app.load_data_panel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {691, 691};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.load_data_panel.Layout.Row = 2;
                app.load_data_panel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x', 401};
                app.load_data_panel.Layout.Row = 1;
                app.load_data_panel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.load_data_panel = uipanel(app.GridLayout);
            app.load_data_panel.Title = 'LOAD DATA';
            app.load_data_tab_group = uitabgroup(app.load_data_panel);
            app.stacksTab = uitab(app.load_data_tab_group);
            app.stacksTab.Title = 'stacks';
            app.matches_list_box = uilistbox(app.stacksTab);
            app.matches_list_box.Multiselect = 'on';
            app.matches_list_box.Tooltip = {'app.matches_list_box.Items'};
            app.matches_list_box.Value = {'Item 1'};
            app.list_matches_button = uibutton(app.stacksTab, 'push');
            app.list_matches_button.ButtonPushedFcn = createCallbackFcn(app, @list_matches_button_pressed, true);
            app.list_matches_button.Text = 'list matches';
            app.add_selected_button = uibutton(app.stacksTab, 'push');
            app.add_selected_button.ButtonPushedFcn = createCallbackFcn(app, @add_selected_button_pushed, true);
            app.add_selected_button.Text = 'add selected';
            app.browse_files_button = uibutton(app.stacksTab, 'push');
            app.browse_files_button.ButtonPushedFcn = createCallbackFcn(app, @browse_files_button_pushed, true);
            app.browse_files_button.Text = 'browse files';
            app.matching_style_drop_down_label = uilabel(app.stacksTab);
            app.matching_style_drop_down_label.Text = 'matching style';
            app.matching_style_drop_down = uidropdown(app.stacksTab);
            app.suffix_drop_down_label = uilabel(app.stacksTab);
            app.suffix_drop_down_label.Text = 'suffix';
            app.suffix_drop_down = uidropdown(app.stacksTab);
            app.trial_edit_field_label = uilabel(app.stacksTab);
            app.trial_edit_field_label.Text = 'trial';
            app.trial_edit_field = uieditfield(app.stacksTab, 'text');
            app.fly_edit_field_label = uilabel(app.stacksTab);
            app.fly_edit_field_label.Text = 'fly';
            app.fly_edit_field = uieditfield(app.stacksTab, 'text');
            app.date_edit_field_label = uilabel(app.stacksTab);
            app.date_edit_field_label.Text = 'date';
            app.date_edit_field = uieditfield(app.stacksTab, 'text');
            app.parent_path_button = uibutton(app.stacksTab, 'push');
            app.parent_path_button.ButtonPushedFcn = createCallbackFcn(app, @parent_path_button_pushed, true);
            app.parent_path_button.Text = '...';
            app.parent_path_edit_field_label = uilabel(app.stacksTab);
            app.parent_path_edit_field_label.Text = 'parent path';
            app.parent_path_edit_field = uieditfield(app.stacksTab, 'text');
            app.stack_compute_pool_panel = uipanel(app.stacksTab);
            app.stack_compute_pool_panel.Title = 'stack compute pool';
            app.stack_compute_pool_list_box = uilistbox(app.stacksTab);
            app.stack_compute_pool_list_box.Multiselect = 'on';
            app.stack_compute_pool_list_box.Tag = 'stack_compute_pool_list_box';
            app.stack_compute_pool_list_box.Tooltip = {'stacks to be analyzed'};
            app.stack_compute_pool_list_box.Value = {'Item 1'};
            app.natural_sort_check_box = uicheckbox(app.stacksTab);
            app.natural_sort_check_box.ValueChangedFcn = createCallbackFcn(app, @natural_sort_check_box_value_changed, true);
            app.natural_sort_check_box.Text = 'natural sort';
            app.load_stack_button = uibutton(app.stacksTab, 'push');
            app.load_stack_button.Text = 'LOAD STACK';
            app.ButtonGroup = uibuttongroup(app.stacksTab);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.outputLabel = uilabel(app.RightPanel);
            app.outputLabel.Text = 'OUTPUT';
            app.cw_text_area = uitextarea(app.RightPanel);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.outputLabel_2 = uilabel(app.RightPanel);
            app.outputLabel_2.Text = 'PLAN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flys(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end