classdef flys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        imgTypeSwitch             matlab.ui.control.DropDown
        refineButton              matlab.ui.control.Button
        updateDisplayButton_2     matlab.ui.control.Button
        plotButton                matlab.ui.control.Button
        processButton             matlab.ui.control.Button
        createROIButton_2         matlab.ui.control.Button
        deleteROIButton_2         matlab.ui.control.Button
        autoSegmentButton         matlab.ui.control.Button
        segmentButton             matlab.ui.control.Button
        refImageChannel           matlab.ui.control.Switch
        pointButton               matlab.ui.control.Button
        minIntensityEditField     matlab.ui.control.EditField
        minintensityLabel         matlab.ui.control.Label
        lineButton                matlab.ui.control.Button
        shiftROIDownButton        matlab.ui.control.Button
        shiftROIUpButton          matlab.ui.control.Button
        maxZButton                matlab.ui.control.Button
        RogueLabel                matlab.ui.control.Label
        downOnePlaneButton        matlab.ui.control.Button
        upOnePlaneButton          matlab.ui.control.Button
        loadROIsButton            matlab.ui.control.Button
        selectAllPlanesButton     matlab.ui.control.Button
        selectNoROIsButton        matlab.ui.control.Button
        selectAllROIsButton       matlab.ui.control.Button
        msgLabel                  matlab.ui.control.Label
        ROIsListBox               matlab.ui.control.ListBox
        browseButton              matlab.ui.control.Button
        plotPanel                 matlab.ui.container.Panel
        saveROIsButton            matlab.ui.control.Button
        deleteROIButton           matlab.ui.control.Button
        createROIButton           matlab.ui.control.Button
        selectedROIEditField      matlab.ui.control.EditField
        andaddittoLabel           matlab.ui.control.Label
        planeSelectDropdown       matlab.ui.control.DropDown
        onLabel                   matlab.ui.control.Label
        drawButton                matlab.ui.control.Button
        updateDisplayButton       matlab.ui.control.Button
        planesListBox             matlab.ui.control.ListBox
        planesLabel               matlab.ui.control.Label
        maxIntensityEditField     matlab.ui.control.EditField
        maxintensityLabel         matlab.ui.control.Label
        trialNumSpinner           matlab.ui.control.Spinner
        trialnumberLabel          matlab.ui.control.Label
        loadRefImgButton          matlab.ui.control.Button
        expDirEditField           matlab.ui.control.EditField
        experimentdirectoryLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)

        ui = input_params_carl([])
        
        defaultExpDir = '~/stacks'; % \\research.files.med.harvard.edu\Neurobio\Wilson Lab\';    % Starting directory for browse button
        expDir                                                      % The directory that the current refImages were loaded from
        refImages = [];                                             % Reference images for all trials
        trialNums = [];                                             % Vector of trial numbers for the loaded refImages
        refImageAxes = [];                                          % Array of axes handles for each plane in the current trial
        axMaxZ = [];                                                % Handle of the axes for plotting max Z projections
        MaxZimg = [];
        currTrialInd = [];                                          % Index of the currently selected trial num
        cm = repmat([ rgb('Blue'); rgb('Green'); rgb('Red'); ...    % List of colors to cycle through for parent ROIs
            rgb('Cyan'); rgb('Magenta'); rgb('Gold'); ...
            rgb('Brown'); rgb('Teal') ; rgb('Purple') ; ...
            rgb('Orange'); rgb('Indigo'); rgb('SpringGreen')], 50, 1);
        currColorInd = 1;                                           % Keeps track of which color is up next
        roiData = [];                                               % Holds information about the current list of ROIs
        defaultSaveName = 'roiDefs';                                % Default value for the save file name prefix
    
    end
    
    methods (Access = private)
        
        % ----------------------- Clear everything that would change between trials ----------------------
        function clear_trial_data(app)
            
            app.currTrialInd = [];
            app.planesListBox.Items = {};
            
            clear_plot_data(app)
            
        end
        
        % ------------------------------------ Clear plotting panel -------------------------------------
        function clear_plot_data(app)
            
            % Hide all axes and plots
            if ~isempty(app.refImageAxes)
                for iPlot = 1:numel(app.refImageAxes)
                    app.refImageAxes{iPlot}.Visible = 'off';
                    app.refImageAxes{iPlot}.Title.Visible = 'off';
                    for iChild = 1:numel(app.refImageAxes{iPlot}.Children)
                        app.refImageAxes{iPlot}.Children(iChild).Visible = 'off';
                    end
                end
            end
            app.planeSelectDropdown.Items = {};
            if ~isempty(app.axMaxZ)
                app.axMaxZ.Visible = 'off';
                app.axMaxZ.Title.Visible = 'off';
                for iChild = 1:numel(app.axMaxZ.Children)
                    app.axMaxZ.Children(iChild).Visible = 'off';
                end
                axesHandlesToChildObjects = findobj(app.axMaxZ, 'Type', 'image');
                if ~isempty(axesHandlesToChildObjects)
                    delete(axesHandlesToChildObjects);
                end 
                if ~isempty(app.MaxZimg)
                    delete(app.MaxZimg);
                end 
            end
        end
        
        % ------------------------------- Update the reference image plots -------------------------------
        function update_display(app)
            
            % Clear plotting panel
            clear_plot_data(app)
            
            % Get the numbers of the currently selected planes
            planeNums = strip(app.planesListBox.Value);
            
            % Calculate the necessary subplot dimensions
            nPlanes = numel(planeNums);
            subplotDim1 = ceil(sqrt(nPlanes));
            subplotDim2 = floor(sqrt(nPlanes));
            if (subplotDim1 * subplotDim2) < nPlanes
                subplotDim2 = ceil(sqrt(nPlanes));
            end
            padSize = 0.01;
            rightMargin = 0;
            axWidth = (1 - rightMargin - ((subplotDim1 + 1) * padSize)) / subplotDim1;
            axHeight = (1 - ((subplotDim2 + 1) * padSize)) / subplotDim2;
            
            % Create axes in correct locations
            roiRefImgAxes = [];
            for yDim = 1:subplotDim2
                for xDim = 1:subplotDim1
                    xPos = (xDim * padSize) + ((xDim-1) * axWidth);
                    yPos = (yDim * padSize) + ((yDim-1) * axHeight);
                    roiRefImgAxes{yDim, xDim} = axes(app.plotPanel, ...
                        'Units', 'Normalized', ...
                        'Position', [xPos, yPos, axWidth, axHeight]);
                end
            end
            
            % Order handles by z-planes, displayed from L-R, top-bottom
            roiRefImgAxes = reshape(flipud(roiRefImgAxes)', 1, numel(roiRefImgAxes));
            
            % Delete any extra (unused) axes
            if numel(roiRefImgAxes) > nPlanes
                for iAx = numel(roiRefImgAxes):-1:(nPlanes + 1)
                    delete(roiRefImgAxes{iAx})
                end
                roiRefImgAxes = roiRefImgAxes(1:nPlanes);
            end
            
            % Plot reference frames for each plane
            for iPlane = 1:nPlanes
                currAxes = roiRefImgAxes{iPlane};
                axes(currAxes)
                axis(currAxes, 'image')
                hold(currAxes, 'on');
                maxIntensity = str2double(app.maxIntensityEditField.Value);
                minIntensity = str2double(app.minIntensityEditField.Value);
                imshow(app.refImages{app.currTrialInd}(:, :, str2double(planeNums{iPlane})), [minIntensity maxIntensity], ...
                    'InitialMagnification', 'fit', 'Parent', currAxes);
                currAxes.Title.String = ['Plane #' planeNums{iPlane}];
                currAxes.UserData = planeNums{iPlane};
                currAxes.Toolbar.Visible = 'off';
                currAxes.Interactions = [];
                axis(currAxes, 'image');
            end
            
            % Check to see if any existing ROIs need to be transferred to the new axes
            for iROI = 1:numel(app.ROIsListBox.Items)
                currROIName = app.ROIsListBox.Items{iROI};
                currROIData = app.roiData(iROI);
                
                % Loop through each subROI
                for iSubROI = 1:numel(currROIData.subROIs)
                    currPlane = currROIData.subROIs(iSubROI).plane;
                    [~, currPlaneInd] = ismember(num2str(currPlane), planeNums);
                    
                    if currPlaneInd
                        
                        % Transfer subROI over to the new parent axes
                        currROIData.subROIs(iSubROI).obj.Parent = roiRefImgAxes{currPlaneInd};
                        
                        % Adjust visibility of subROIs according to ROIsListBox selection status
                        if ismember(currROIName, app.ROIsListBox.Value)
                            currROIData.subROIs(iSubROI).obj.Visible = 'on';
                        else
                            currROIData.subROIs(iSubROI).obj.Visible = 'off';
                        end
                        
                    end
                end%iSubROI
            end%iROI
            
            % Update stored axes handles
            oldAxes = app.refImageAxes(str2double(planeNums));
            app.refImageAxes(str2double(planeNums)) = roiRefImgAxes;
            for iAx = 1:numel(oldAxes)
                delete(oldAxes{iAx})
            end
            
            % (Re)populate the plane select dropdown
            for iPlane = 1:nPlanes
                app.planeSelectDropdown.Items{iPlane} = ['Plane ', planeNums{iPlane}];
            end
            if numel(app.planeSelectDropdown.Items) > 0
                app.planeSelectDropdown.Value = app.planeSelectDropdown.Items{1};
            end
            
        end%function
        
        % ------------------------------- Shift display up or down one plane -------------------------------
        function shift_display(app, direction)
            
            if strcmp(direction, 'up')
                
                % Shift all selected planes down by one plane number index (corresponds to a shift up in Z)
                % unless the dorsalmost plane is already selected.
                currPlanes = app.planesListBox.Value;
                currPlaneInds = find(ismember(app.planesListBox.Items, currPlanes));
                if min(currPlaneInds) > 1
                    currPlaneInds = currPlaneInds - 1;
                    app.planesListBox.Value = app.planesListBox.Items(currPlaneInds);
                    update_display(app);
                end
                
            elseif strcmp(direction, 'down')
                
                % Shift all selected planes up by one plane number index (corresponds to a shift down in Z)
                % unless the ventralmost plane is already selected.
                currPlanes = app.planesListBox.Value;
                currPlaneInds = find(ismember(app.planesListBox.Items, currPlanes));
                if max(currPlaneInds) < numel(app.planesListBox.Items)
                    currPlaneInds = currPlaneInds + 1;
                    app.planesListBox.Value = app.planesListBox.Items(currPlaneInds);
                    update_display(app);
                end
            end
            
            
        end
    
    end%methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            

            % Prompt user for experiment directory
            if isempty(app.expDirEditField.Value)
                folder = app.defaultExpDir;
            else
                folder = app.expDirEditField.Value;
            end
            expDir = uigetdir(folder, 'Select a directory containing reference image files');
            if ~expDir
                expDir = '';
            end
            app.expDirEditField.Value = expDir; %#ok<*ADPROPLC>
            %             app.expDirEditField.Value = 'D:\Dropbox (HMS)\2P Data\Imaging Data\20191113-1_38A11-ChR-6m\ProcessedData';
            
        end

        % Button pushed function: loadRefImgButton
        function loadRefImgButtonPushed(app, event)
            
            % Determine channel type of ref images to load
            chType = app.refImageChannel.Value;  
            if contains (chType, 'Ch 2')
                chType = 'ch2';
            else 
                chType = 'ch1';
            end
            
            % Determine whether to look for raw or registered files
            imgType = app.imgTypeSwitch.Value;
            if contains(imgType, 'raw')
                imgType = 'raw';
            elseif contains(imgType, 'deep')
                imgType = 'deepcad';
            else
                imgType = 'reg';
            end
            
            % Attempt to find reference image files in the experiment directory
            app.expDir = app.expDirEditField.Value;

            pthstacks = find_preprocessed_files(pthparent_local=app.expDir);

            refImgFiles = dir(fullfile(app.expDir, '**', 'refImages_', imgType, '_', chType, '*.mat'));
            if numel(refImgFiles) == 0
                uialert(app.UIFigure, 'Error: could not find any reference image files of that type in the selected directory')
            else
                
                % Clear any existing data
                app.trialNums = [];
                app.refImages = [];
                clear_trial_data(app);                
                % Extract trial numbers and compile images from each file
                for iFile = 1:numel(refImgFiles)
                    load(fullfile(refImgFiles(iFile).folder, refImgFiles(iFile).name), 'refImages') % refImages: [y, x, plane]
                    refImages = rescale(refImages); % normalise
                    trialNum =  get_trialNum(refImgFiles(iFile).name); %str2double(regexp(refImgFiles(iFile).name, '(?<=trial_)...(?=.mat)', 'match', 'once'));
                    if isnan(trialNum)
                        trialNum = 1;
                    elseif isempty(trialNum)
                        trialNum = 1;
                    end
                    app.trialNums(iFile) = trialNum;
                    app.refImages{iFile} = refImages;
                end
                app.msgLabel.Text = [' Loaded ', num2str(numel(refImgFiles)), ' reference image files'];
                
                % Create axes for displaying maxZ projections if they do not already exist
                if isempty(app.axMaxZ)
                    app.axMaxZ = axes(app.plotPanel, ...
                        'Units', 'Normalized', ...
                        'Position', [0.01, 0.01, 0.98, 0.98]);
                end
                app.axMaxZ.Visible = 'off';
            end
            
            % Set trial number to first trial and then trigger the spinner's callback
            app.trialNumSpinner.Value = app.trialNums(1);
            trialNumSpinnerValueChanged(app);
            
        end

        % Value changed function: trialNumSpinner
        function trialNumSpinnerValueChanged(app, event)
            
            app.msgLabel.Text = ' Loading new trial...';
            
            % Clear data from the previously selected trial (unless ref images were just loaded)
            if ~isempty(app.planesListBox.Items)
                oldSelectedPlanes = app.planesListBox.Value;
                clear_trial_data(app)
            end
            
            % Determine whether new value is one of the loaded data's trial numbers
            if sum(app.trialNums == app.trialNumSpinner.Value)
                
                % Get index of the current trial
                app.currTrialInd = find(app.trialNums == app.trialNumSpinner.Value);
                
                % Count number of planes for current trial and add them to planes list
                nPlanes = size(app.refImages{app.currTrialInd}, 3);
                
                % Populate the plane select listBox
                for iPlane = 1:nPlanes
                    app.planesListBox.Items{iPlane} = pad(num2str(iPlane), 5, 'left');
                end
                try
                    % Attempt to re-select the same planes from before
                    app.planesListBox.Value = oldSelectedPlanes;
                catch
                    % Select all planes by default if the list has changed
                    app.planesListBox.Value = app.planesListBox.Items;
                end
                % Create temporary placeholder axes if necessary
                nAxes = numel(app.refImageAxes);
                if nAxes < nPlanes
                    for iAx = (nAxes + 1):nPlanes
                        app.refImageAxes{iAx} = axes(app.plotPanel, 'Visible', 'off');
                    end
                end
                
                % Update the display
                update_display(app);
                
                app.msgLabel.Text = [' Trial ', num2str(app.trialNumSpinner.Value), ' loaded'];
            else
                % Leave everything blank if there's no data for that trial
                app.msgLabel.Text = ' Warning: no reference image was found for this trial number';
            end
            
        end

        % Button pushed function: createROIButton
        function createROIButtonPushed(app, event)
            
            % Ask user to enter a name for the ROI
            opts.Interpreter = 'tex';
            roiName = inputdlg('\fontsize{12}Please enter a name for the new ROI:', '', 1 , {''}, opts);
            
            % Make sure they entered a valid name
            currROIs = app.ROIsListBox.Items;
            if isempty(roiName)
                app.msgLabel.Text = ' ROI creation cancelled';
            elseif strcmpi(roiName{1}, '') || (~isempty(currROIs) && any(cellfun(@strcmp, currROIs, repmat(roiName, size(currROIs)))))
                uialert(app.UIFigure, ...
                    "Error: please enter a valid ROI name. Cannot be an empty string or a duplicate of an existing ROI's name.", 'Error')
            else
                % Add a new entry to the ROIs listbox and select it
                app.roiData(end + 1).name = roiName{:};
                app.roiData(end).subROIs = struct('obj', [], 'plane', [], 'refImage', []);
                app.roiData(end).color = app.cm(app.currColorInd, :);
                app.currColorInd = app.currColorInd + 1;
                app.ROIsListBox.Items{end + 1} = app.roiData(end).name;
                app.ROIsListBox.Value = app.roiData(end).name;
                ROIsListBoxValueChanged(app);
            end
            
            
        end

        % Value changed function: ROIsListBox
        function ROIsListBoxValueChanged(app, event)
            
            selectedItems = app.ROIsListBox.Value;
            
            if numel(selectedItems) > 1 || isempty(selectedItems)
                app.selectedROIEditField.Value = '[select an ROI from the list]';
            else
                selectedItems = selectedItems{:};
                app.selectedROIEditField.Value = selectedItems;
            end
            
        end

        % Button pushed function: deleteROIButton
        function deleteROIButtonPushed(app, event)
            
            selectedItems = app.ROIsListBox.Value;
            if ~isempty(selectedItems)
                selection = uiconfirm(app.UIFigure, 'Are you sure you want to delete the selected ROIs?', ...
                    'Confirm deletion');
                if strcmpi(selection, 'OK')
                    
                    % Make a list of the names of ROIs to be deleted
                    deleteROINames = {};
                    for iROI = 1:numel(selectedItems)
                        deleteROINames{iROI} = selectedItems{iROI};
                    end
                    
                    % Delete the ROIs in the listBox items and in the roiData property
                    delItems = ismember(app.ROIsListBox.Items, deleteROINames);
                    app.ROIsListBox.Items(delItems) = [];
                    app.roiData(delItems) = [];
                    app.msgLabel.Text = [' Deleted ', num2str(numel(delItems)), ' ROIs'];
                    
                    % Reset the selected ROI edit field
                    app.selectedROIEditField.Value = '[select an ROI from the list]';
                    
                    % Update the display in the plot panel
                    update_display(app)
                else
                    app.msgLabel.Text = ' ROI deletion cancelled';
                end
            end
            
        end

        % Button pushed function: drawButton
        function drawButtonPushed(app, event)
            
            % Get parent ROI info
            parentROIIndex = strcmp(app.ROIsListBox.Items, app.selectedROIEditField.Value);
            parentROI = app.roiData(parentROIIndex);
            
            % Are we looking at one or more than one plane?
            currPlaneNums = str2double(strip(app.planesListBox.Value));
            sz = size(currPlaneNums,2);
            
            % EW edits: calling maxZButtonPushed(app, event)erased the
            % previously saved ROI (not sure why, specifically within the
            % maxZButtonPushed function the line calling imshow seems to be
            % the issue)
            
            % Get correct image axes
            if sz>1 % is empty when looking at maxZ
                %maxZButtonPushed(app, event) % maxZ view
                targetPlane = currPlaneNums;
                targetAxes = app.axMaxZ;
            elseif ~isempty(app.planeSelectDropdown.Value)
                % Check plane and ROI list selection status
                targetPlaneStr = app.planeSelectDropdown.Value;
                targetPlane = regexp(targetPlaneStr, '(?<=Plane ).*', 'match', 'once');
                % Grab handle to the appropriate axes
                for iPlot = 1:numel(app.refImageAxes)
                    if strcmp(app.refImageAxes{iPlot}.UserData, targetPlane)
                        targetAxes = app.refImageAxes{iPlot};
                    end
                end
                targetPlane = str2double(targetPlane);
            else
                uialert(app.UIFigure, 'Error: please select at least one plane!', 'Error')
                return
            end
            
            %% Draw ROI
            if isnumeric(targetPlane) && ~isempty(parentROI) && ~strcmpi(parentROI.name, '[select an ROI from the list]')
               
                % Prompt user to draw the new ROI
                titleStr = targetAxes.Title.String;
                targetAxes.Title.String = '*** DRAW AN ROI OVER ALL SELECTED PLANES ***';
                newROI = images.roi.Freehand(targetAxes, 'Color', parentROI.color, 'FaceAlpha', 0.05);
                newROI.draw();
                newROI.Label = parentROI.name;
                targetAxes.Title.String = titleStr;
                
                % Save information about new ROI
                for plane = targetPlane
                    
                    % Create ROI object
                    planstr = num2str(plane);
                    for iPlot = 1:numel(app.refImageAxes)
                        if strcmp(app.refImageAxes{iPlot}.UserData, planstr)
                            targetAxes = app.refImageAxes{iPlot};
                        end
                    end
                    sROI = images.roi.Freehand(targetAxes, 'Color', parentROI.color, 'FaceAlpha', 0.05);
                    sROI.Position = newROI.Position;
                    sROI.Label = newROI.Label;
                    %sROI.Waypoints(:) = true;
                    
                    % Update plane
                    if isempty(app.roiData(parentROIIndex).subROIs(1).obj)
                        app.roiData(parentROIIndex).subROIs(1).obj = sROI;
                    else
                        app.roiData(parentROIIndex).subROIs(end + 1).obj = sROI;
                    end
                    app.roiData(parentROIIndex).subROIs(end).plane = plane; 
                end
                
                % Remove original
                clear newROI
                
                % Update the display in the plot panel
                update_display(app)
            else
                uialert(app.UIFigure, 'Error: please make sure you have selected a valid plane and parent ROI!', 'Error')
            end
        end

        % Button pushed function: updateDisplayButton
        function updateDisplayButtonPushed(app, event)
            app.msgLabel.Text = ' updating plots...';
            update_display(app)
            app.msgLabel.Text = ' plots updated';
        end

        % Button pushed function: saveROIsButton
        function saveROIsButtonPushed(app, event)
            
            
            % ----------- CHECK FOR ERRORS ------------
            proceed = 1;
            
            % Make sure there is at least one selected ROI in the list
            if isempty(app.ROIsListBox.Value)
                proceed = 0;
                uialert(app.UIFigure, 'Error: no ROIs are selected in the ROI list!', 'Error');
                app.msgLabel.Text = ' Saving cancelled';
            else
                
                % Make sure each selected ROI has at least one subROI
                for iROI = 1:numel(app.ROIsListBox.Value)
                    currROIData = app.roiData(strcmp(app.ROIsListBox.Value{iROI}, {app.roiData.name}));
                    if isempty(currROIData.subROIs(1).obj)
                        proceed = 0;
                        uialert(app.UIFigure, ['Error: parent ROI "', currROIData.name, ...
                            '" does not have any subROIs so it cannot be saved!'], 'Error');
                        app.msgLabel.Text = ' Saving cancelled';
                        break
                    else
                        
                        % Make sure no sub-ROI has a plane number greater than the current trial's nPlanes
                        for iSubROI = 1:numel(currROIData.subROIs)
                            if currROIData.subROIs(iSubROI).plane > numel(app.planesListBox.Items)
                                proceed = 0;
                                uialert(app.UIFigure, ['Error: at least one sub-ROI of parent ROI "', currROIData.name, ...
                                    '" has a plane number greater than the number of planes in reference images for the ', ...
                                    'current trial!'], 'Error');
                                app.msgLabel.Text = ' Saving cancelled';
                                break
                            end
                        end%iSubROI
                        if ~proceed
                            break % Exit the outer loop as well if the inner one found an error
                        end
                    end%if
                end%iROI
                
                % Warn user if there are unselected ROIs in the listBox
                if numel(app.ROIsListBox.Value) ~= numel(app.ROIsListBox.Items)
                    selection = uiconfirm(app.UIFigure, ['There is at least one unselected ROI in the list that will not be saved', ...
                        '...are you sure you want to proceed?'], 'Confirm save');
                    if ~strcmpi(selection, 'OK')
                        proceed = 0;
                        app.msgLabel.Text = ' Saving cancelled';
                    end
                end
                
                % Warn user if there are any sub-ROIs from selected ROIs that are not currently shown on the plots
                warnUser = 0;
                for iROI = 1:numel(app.ROIsListBox.Value)
                    currROIData = app.roiData(strcmp(app.ROIsListBox.Value{iROI}, {app.roiData.name}));
                    for iSubROI = 1:numel(currROIData.subROIs)
                        if strcmpi(currROIData.subROIs(iSubROI).obj.Visible, 'off')
                            warnUser = 1;
                            break
                        end
                    end%iSubROI
                end%iROI
                if warnUser
                    selection = uiconfirm(app.UIFigure, ['Warning: one or more sub-ROIs are not currently displayed in the ' ...
                        'reference image plots, but they will still be included in the saved ', ...
                        'ROI data if you continue. Are you sure you want to proceed?'], 'Confirm save');
                    if ~strcmpi(selection, 'OK')
                        proceed = 0;
                        app.msgLabel.Text = ' Saving cancelled';
                    end
                end
            end%if
            
            % Prompt user for a file name
            if proceed
                opts.Interpreter = 'tex';
                fileNamePrefix = inputdlg('\fontsize{12}Please enter a prefix for the save file name:', '', 1 , {app.defaultSaveName}, opts);
                app.defaultSaveName = fileNamePrefix{:}; % Save whatever the user entered to be the new default
                
                % Warn user if saving will overwrite an existing file
                imgType = app.imgTypeSwitch.Value;
                switch(imgType)
                case 'raw' 
                  type = 'raw';
                case 'deepcad'
                  type = 'deepcad';
                otherwise
                  type = 'registration';
                end         
                saveDir = fullfile(app.expDir, [type '_' pad(num2str(app.trialNumSpinner.Value), 3, 'left', '0')]);
                saveFile = fullfile(saveDir, [fileNamePrefix{:}, '_trial_', pad(num2str(app.trialNumSpinner.Value), 3, 'left', '0'), '.mat']);
                if ~exist(saveDir, 'dir')
                    mkdir(saveDir)
                end 
                if exist(saveFile, 'file')
                    selection = uiconfirm(app.UIFigure, ['There is an existing file with that name that will be overwritten by this ', ...
                        'save...are you sure you want to proceed?'], 'Confirm file overwrite');
                    if ~strcmpi(selection, 'OK')
                        proceed = 0;
                        app.msgLabel.Text = ' Saving cancelled';
                    end
                end
            end
            
            
            % ----------- SAVE ROI DATA ------------
            
            if proceed
                
                % Process data for each ROI
                selectedROIData = app.roiData(ismember(app.ROIsListBox.Value, {app.roiData.name}));
                for iROI = 1:numel(selectedROIData)
                    for iSubROI = 1:numel(selectedROIData(iROI).subROIs)
                        
                        currSubROIObj = selectedROIData(iROI).subROIs(iSubROI).obj;
                        if ~isempty(currSubROIObj) && isvalid(currSubROIObj)
                           % Link a reference image to each sub-ROI
                            planeNum = selectedROIData(iROI).subROIs(iSubROI).plane;
                            selectedROIData(iROI).subROIs(iSubROI).refImage = app.refImages{app.currTrialInd}(:, :, planeNum);
                        
                            % Extract properties from ROI object
                            selectedROIData(iROI).subROIs(iSubROI).position = currSubROIObj.Position;
                            maskable = contains(class(currSubROIObj), ["Freehand", "Polyline", "Polygon"],'IgnoreCase',true);
                            if maskable
                              selectedROIData(iROI).subROIs(iSubROI).mask = currSubROIObj.createMask();
                            else
                              selectedROIData(iROI).subROIs(iSubROI).mask = [];
                            end
                        end
                        
                    end%iSubROI
                end%iROI
                
                % Save ROI data
                roiDefs = selectedROIData;
                save(saveFile, 'roiDefs', '-v7.3');
                app.msgLabel.Text = [' ROIs saved for trial #', num2str(app.trialNumSpinner.Value)];
            end
            
            
        end

        % Button pushed function: selectAllROIsButton
        function selectAllROIsButtonPushed(app, event)
            app.ROIsListBox.Value = app.ROIsListBox.Items;
        end

        % Button pushed function: selectNoROIsButton
        function selectNoROIsButtonPushed(app, event)
            app.ROIsListBox.Value = {};
        end

        % Button pushed function: selectAllPlanesButton
        function selectAllPlanesButtonPushed(app, event)
            app.planesListBox.Value = app.planesListBox.Items;
            %               test = app.roiData
        end

        % Button pushed function: loadROIsButton
        function loadROIsButtonPushed(app, event)
            
            % Ask user to select an ROI def file
            proceed = 1;
            [file, path] = uigetfile(fullfile(app.expDir, '*.mat'), 'Select an ROI def file');
            if ischar(file) && ischar(path)
                load(fullfile(path, file), 'roiDefs');
            else
                proceed = 0;
            end
            
            % Check if import will overwrite existing ROIs
            if proceed
                for iROI = 1:numel(roiDefs)
                    if ismember(roiDefs(iROI).name, app.ROIsListBox.Items)
                        selection = uiconfirm(app.UIFigure, ['Warning: the import you have requested will overwrite one or ', ...
                            'more existing ROIs! Do you want to continue?'], 'Confirm ROI overwrite');
                        if ~strcmpi(selection, 'OK')
                            proceed = 0;
                        end
                        break % Apply user's first selection to all imports
                    end
                end
            end
            
            % Import ROIs
            app.msgLabel.Text = ' Importing ROIs...';
            if proceed
                for iROI = 1:numel(roiDefs)
                    
                    % Delete any existing ROI with the same name as the current import
                    delItem = ismember(roiDefs(iROI).name, app.ROIsListBox.Items);
                    if delItem
                        app.ROIsListBox.Items(delItem) = [];
                        app.roiData(delItem) = [];
                    end
                    
                    % Create a new ROI with the appropriate name
                    app.roiData(end + 1).name = roiDefs(iROI).name;
                    app.roiData(end).subROIs = roiDefs(iROI).subROIs;
                    app.roiData(end).color = app.cm(app.currColorInd, :); % Change the color to work with any existing ROIs
                    app.currColorInd = app.currColorInd + 1;
                    app.ROIsListBox.Items{end + 1} = app.roiData(end).name;
                    app.ROIsListBox.Value{end + 1} = app.roiData(end).name; % So it's not deleted when the display updates
                    
                    % Transfer the subROI objects over to the appropriate axes
                    for iSubROI = 1:numel(app.roiData(end).subROIs)
                        targetAxes = app.refImageAxes{app.roiData(end).subROIs(iSubROI).plane};
                        app.roiData(end).subROIs(iSubROI).obj.Color = app.roiData(end).color;
                        app.roiData(end).subROIs(iSubROI).obj.UIContextMenu = [];
                        app.roiData(end).subROIs(iSubROI).obj.Parent = targetAxes;
                    end
                    
                    % Update display to plot the new ROIs
                    update_display(app)
                end
                app.msgLabel.Text = [' Imported ', num2str(numel(roiDefs)), ' ROIs'];
            else
                app.msgLabel.Text = ' ROI import cancelled';
            end
            
        end

        % Button pushed function: upOnePlaneButton
        function upOnePlaneButtonPushed(app, event)
            shift_display(app, 'up');
        end

        % Button pushed function: downOnePlaneButton
        function downOnePlaneButtonPushed(app, event)
            shift_display(app, 'down');
        end

        % Button pushed function: maxZButton
        function maxZButtonPushed(app, event)
            
            if ~isempty(app.axMaxZ)
                
                % Hide existing plots
                clear_plot_data(app);
                
                % Get max Z projection for selected planes
                currPlaneNums = str2double(strip(app.planesListBox.Value));
                currPlanesMaxZ = max(app.refImages{app.trialNumSpinner.Value}(:, :, currPlaneNums), [], 3); % --> [y, x];
                % currPlanesMaxZ = median(app.refImages{app.trialNumSpinner.Value}(:, :, currPlaneNums), 3); % --> [y, x];
                
                % Show max Z plot
                app.axMaxZ.Visible = 'on';
                axes(app.axMaxZ)
                axis(app.axMaxZ, 'image')
                %                 hold(app.axMaxZ, 'on');
                maxIntensity = str2double(app.maxIntensityEditField.Value);
                minIntensity = str2double(app.minIntensityEditField.Value);
                imshow(currPlanesMaxZ, [minIntensity maxIntensity], 'InitialMagnification', 'fit', 'Parent', app.axMaxZ);
                app.axMaxZ.Title.String = 'Max Z projection';
                app.axMaxZ.Toolbar.Visible = 'off';
                app.axMaxZ.Interactions = [];
                axis(app.axMaxZ, 'image');
                
                
            end
        end

        % Button pushed function: shiftROIUpButton
        function shiftROIUpButtonPushed(app, event)
            
            % Shift all SubROIs of the selected ROI up one plane (as long as there are none on the first plane)
            if numel(app.ROIsListBox.Value) == 1
                currROIName = app.ROIsListBox.Value{:};
                currROIIdx = strcmp(currROIName, {app.roiData.name});
                subROIPlanes = [app.roiData(currROIIdx).subROIs.plane];
                if min(subROIPlanes) > 1
                    for iSubROI = 1:numel(app.roiData(currROIIdx).subROIs)
                        newPlane = app.roiData(currROIIdx).subROIs(iSubROI).plane - 1;
                        app.roiData(currROIIdx).subROIs(iSubROI).plane = newPlane;
                        app.roiData(currROIIdx).subROIs(iSubROI).refImage = app.refImages{newPlane};
                        app.roiData(currROIIdx).subROIs(iSubROI).obj.Parent = app.refImageAxes{newPlane};
                    end
                end
            end
            update_display(app)
        end

        % Button pushed function: shiftROIDownButton
        function shiftROIDownButtonPushed(app, event)
            
            % Shift all SubROIs of the selected ROI down one plane (as long as there are none on the last plane)
            if numel(app.ROIsListBox.Value) == 1
                currROIName = app.ROIsListBox.Value{:};
                currROIIdx = strcmp(currROIName, {app.roiData.name});
                subROIPlanes = [app.roiData(currROIIdx).subROIs.plane];
                if max(subROIPlanes) < numel(app.refImages)
                    for iSubROI = 1:numel(app.roiData(currROIIdx).subROIs)
                        newPlane = app.roiData(currROIIdx).subROIs(iSubROI).plane + 1;
                        app.roiData(currROIIdx).subROIs(iSubROI).plane = newPlane;
                        app.roiData(currROIIdx).subROIs(iSubROI).refImage = app.refImages{newPlane};
                        app.roiData(currROIIdx).subROIs(iSubROI).obj.Parent = app.refImageAxes{newPlane};
                    end
                end
            end
            update_display(app)
        end

        % Button pushed function: lineButton
        function lineButtonPushed(app, event)
            % Check plane and ROI list selection status
            targetPlaneStr = app.planeSelectDropdown.Value;
            targetPlane = regexp(targetPlaneStr, '(?<=Plane ).*', 'match', 'once');
            
            % Get parent ROI info
            parentROIIndex = strcmp(app.ROIsListBox.Items, app.selectedROIEditField.Value);
            parentROI = app.roiData(parentROIIndex);
            
            if isnumeric(str2double(targetPlane)) && ~isempty(parentROI) && ~strcmpi(parentROI.name, '[select an ROI from the list]')
                
                rowcol = contains(parentROI.name,["row", "column"]);
                if ~rowcol
                   uialert(app.UIFigure, 'Error: please assign to an ROI with "row", if a horizontal line or "column" if vertical, in its name!', 'Error')
                else
                    % Grab handle to the appropriate axes
                    for iPlot = 1:numel(app.refImageAxes)
                        if strcmp(app.refImageAxes{iPlot}.UserData, targetPlane)
                            targetAxes = app.refImageAxes{iPlot};
                        end
                    end
                    
                    % Prompt user to draw the new ROI
                    titleStr = targetAxes.Title.String;
                    targetAxes.Title.String = '*** DRAW A LINE ***';
                    newROI = images.roi.Polyline(targetAxes, 'Color', parentROI.color);
                    newROI.draw();
                    newROI.Label = parentROI.name;
                    targetAxes.Title.String = titleStr;
                    
                    % Save information about new ROI
                    if isempty(app.roiData(parentROIIndex).subROIs(1).obj)
                        app.roiData(parentROIIndex).subROIs(1).obj = newROI;
                    else
                        app.roiData(parentROIIndex).subROIs(end + 1).obj = newROI;
                    end
                    app.roiData(parentROIIndex).subROIs(end).plane = str2double(targetPlane);
                end
            else
                uialert(app.UIFigure, 'Error: please make sure you have selected a valid plane and parent ROI!', 'Error')
            end
        end

        % Button pushed function: pointButton
        function pointButtonPushed(app, event)
            % Check plane and ROI list selection status
            targetPlaneStr = app.planeSelectDropdown.Value;
            targetPlane = regexp(targetPlaneStr, '(?<=Plane ).*', 'match', 'once');
           
            % Get parent ROI info
            parentROIIndex = strcmp(app.ROIsListBox.Items, app.selectedROIEditField.Value);
            parentROI = app.roiData(parentROIIndex);
            
            if isnumeric(str2double(targetPlane)) && ~isempty(parentROI) && ~strcmpi(parentROI.name, '[select an ROI from the list]')
                
                mid = contains(parentROI.name,'mid');
                if ~mid
                   uialert(app.UIFigure, 'Error: please assign to an ROI with "mid" in its name!', 'Error')
                else
                   % Grab handle to the appropriate axes
                    for iPlot = 1:numel(app.refImageAxes)
                        if strcmp(app.refImageAxes{iPlot}.UserData, targetPlane)
                            targetAxes = app.refImageAxes{iPlot};
                        end
                    end
                    
                    % Prompt user to draw the new ROI
                    titleStr = targetAxes.Title.String;
                    targetAxes.Title.String = '*** DOT THE MIDPOINT ***';
                    newROI = images.roi.Crosshair(targetAxes, 'Color', parentROI.color);
                    newROI.draw();
                    newROI.Label = parentROI.name;
                    targetAxes.Title.String = titleStr;
                    
                    % Save information about new ROI
                    if isempty(app.roiData(parentROIIndex).subROIs(1).obj)
                        app.roiData(parentROIIndex).subROIs(1).obj = newROI;
                    else
                        app.roiData(parentROIIndex).subROIs(end + 1).obj = newROI;
                    end
                    app.roiData(parentROIIndex).subROIs(end).plane = str2double(targetPlane); 
                end
            else
                uialert(app.UIFigure, 'Error: please make sure you have selected a valid plane and parent ROI!', 'Error')
            end
        end

        % Button pushed function: segmentButton
        function segmentStructure(app, event)
            
            %% Select planes to use in our segmentation
            currPlaneNums = str2double(strip(app.planesListBox.Value));
            currPlanesMaxZ = max(app.refImages{app.trialNumSpinner.Value}(:, :, currPlaneNums), [], 3); % --> [y, x];;
            
            if ~isempty(app.axMaxZ)
                
                % Hide existing plots
                clear_plot_data(app);

                % Show max Z plot
                app.axMaxZ.Visible = 'on';
                axes(app.axMaxZ)
                axis(app.axMaxZ, 'image')
                maxIntensity = str2double(app.maxIntensityEditField.Value);
                minIntensity = str2double(app.minIntensityEditField.Value);
                app.MaxZimg = imshow(currPlanesMaxZ, [minIntensity maxIntensity], 'InitialMagnification', 'fit', 'Parent', app.axMaxZ);
                app.axMaxZ.Title.String = 'Max Z projection';
                app.axMaxZ.Toolbar.Visible = 'off';
                app.axMaxZ.Interactions = [];
                axis(app.axMaxZ, 'image');
            end
           
            %% Incorporate
            if  ~isempty(currPlaneNums)
         
                % Prompt user to draw the new ROI
                titleStr = app.axMaxZ.Title.String;
                app.axMaxZ.Title.String = '*** DRAW A MASK REGION ***';
                newROI = images.roi.Freehand(app.axMaxZ, 'Color', 'magenta', 'FaceAlpha', 0.05);
                newROI.draw();
                newROI.Label = 'temp';
                app.axMaxZ.Title.String = titleStr;
                mask = newROI.createMask(app.MaxZimg);
                    
                %% extract midline axis, and subsample for centroid locations
                opts.Interpreter = 'tex';
                answer = inputdlg('\fontsize{12}How many control points?:', '', 1 , {'0'}, opts);
                if isempty(answer)
                    maxZButtonPushed(app, event)
                    return
                end
                n_centroid = round(str2double(answer{1})/2);
                if n_centroid == 0
                    
                    % Manually
                    app.axMaxZ.Title.String = '*** DRAW LINE FOR SEGMENTATION ***';
                    
                    % Prompt user to draw a line
                    app.axMaxZ.Title.String = '*** DRAW A LINE ***';
                    pLine = images.roi.Polyline(app.axMaxZ, 'Color', 'magenta');
                    pLine.draw();
                    newROI.Label = 'temp';
                    app.axMaxZ.Title.String = titleStr;
                    P = pLine.Position;
                    x = P(:,1);
                    y = P(:,2);
                    centroids = [y,x];
                    Ncentroid = size(x, 1);
                    cmap = cbrewer2('set1',Ncentroid);
                else               
                    % Automatically
                    app.axMaxZ.Title.String = '*** AUTOMATIC SEGMENTATION ACROSS MIDLINE ***';
                    [y_mask,x_mask] = find(mask);                                               %find the cartesian coordinates of points in the mask, just to find the minimum axis length
                    min_axis        = min(range(x_mask),range(y_mask));
                    mid             = bwskel(mask,'MinBranchLength',min_axis);                  %find the midline as the skeleton, shaving out all sub branches that are smaller than the minimum axis length
                    [y_mid,x_mid]   = find(mid);                                                %by definition, the skeleton has to be at least as long as the min width of the roi, so shave out subbranches that are shorter than that.

                    G = graph(pdist2([x_mid,y_mid],[x_mid,y_mid],'Chebychev') == 1);            %create a graph that connects adjacent points in an image. chebychev distance is chess move distance, aka touching pixels
                    H = rmedge(G,find(all(ismember(G.Edges.EndNodes,find(degree(G) > 2)),2)));  %if a pixel is connected to more than 2 pixels, remove the unnecessary edge
                    
                    k = find(degree(H) == 1, 1);                                                %define the starting point as the first node with only one adjacent pixel. THIS IS FRAGILE FOR CIRCLES
                    if isempty(k)
                        k = 1;
                    end
                    v = dfsearch(H,k);                                                          %discover all points in the graph, starting at our starting point. return the index of discovery (such that you traverse the shortest path)
                    x_mid = x_mid(v);                                                           %reorder the coordinates in order of discovery
                    y_mid = y_mid(v);
                    
                    xq          = -min_axis:(length(x_mid)+min_axis);                             %extend the midline so that it reaches the border of the mask. extrapolate as many points as the minimum axis length
                    x_mid       = round(interp1(1:length(x_mid),x_mid,xq,'linear','extrap'));
                    y_mid       = round(interp1(1:length(y_mid),y_mid,xq,'linear','extrap'));
                    
                    idx         = ismember([x_mid',y_mid'],[x_mask,y_mask],'rows');                 %keep only the points that exist within the mask
                    x_mid       = x_mid(idx);
                    y_mid       = y_mid(idx);

                    xq = linspace(1,length(y_mid),n_centroid*4+1)';           %set query points for interpolation (how many centroids we'll want)
                    xq = xq(2:2:(end-1));
                    centroids = [interp1(1:length(y_mid),y_mid,xq),interp1(1:length(x_mid),x_mid,xq)]; %interpolate x and y coordinates, now that they are ordered, into evenly spaced centroids (this allows one to oversample the number of pixels, if desired)

                    Ncentroid = 2*n_centroid;
                    cmap = cbrewer2('set1',Ncentroid);  %set a colormap to plot each centroid in a different color, and which repeats per hemisphere 
                end
                
                %% Show result
                clear_plot_data(app);
                
                % Show max Z plot
                app.axMaxZ.Visible = 'on';
                axes(app.axMaxZ)
                axis(app.axMaxZ, 'image')
                maxIntensity = str2double(app.maxIntensityEditField.Value);
                minIntensity = str2double(app.minIntensityEditField.Value);
                imshow(currPlanesMaxZ, [minIntensity maxIntensity], 'InitialMagnification', 'fit', 'Parent', app.axMaxZ);
                hold(app.axMaxZ, 'on');
                plot(app.axMaxZ, centroids(:,2), centroids(:,1), 'magenta')  %plot the midline in magenta
                scatter(app.axMaxZ, centroids(:,2),centroids(:,1),[],cmap,'filled') %show the centroids in each of their colors
                
                % Handles
                app.axMaxZ.Title.String = 'Segmentation of ROI by midline control points';
                app.axMaxZ.Toolbar.Visible = 'off';
                app.axMaxZ.Interactions = [];
                axis(app.axMaxZ, 'image');
                
                % assign each pixel to a centroid
                [y_coor,x_coor] = find(mask);                   %find coordinates of every pixel in the mask
                [~,idx] = pdist2(centroids,[y_coor,x_coor],'euclidean','smallest',1); %find the index of the centroid that is closest to each pixel in the mask
                for i = 1:Ncentroid                          %overlay each pixel in its indexed color onto the pb image. 
                    scatter(app.axMaxZ, x_coor(idx == i),y_coor(idx == i),'filled','MarkerFaceColor',cmap(i,:),'MarkerFaceAlpha',0.1)
                end
                
                % User input
                opts.Interpreter = 'tex';
                opts.Default = 'Yes';
                quest = 'Create ROIs?';
                choice = questdlg(quest,'midline segmentation','Yes','No',opts);
                if strcmp(choice,'Yes')
                    % Save each centroid shape
                    opts.Interpreter = 'tex';
                    majorName = inputdlg('\fontsize{12}Please enter a name for the new ROI cluster:', '', 1 , {''}, opts);
                    for i = 1:Ncentroid
                            
                        % Check name  
                        roiName = {[char(majorName) '_' num2str(i-1)]};
                        currROIs = app.ROIsListBox.Items;
                        if isempty(roiName)
                            app.msgLabel.Text = ' ROI creation cancelled';
                        elseif strcmpi(roiName{1}, '') || (~isempty(currROIs) && any(cellfun(@strcmp, currROIs, repmat(roiName, size(currROIs)))))
                            uialert(app.UIFigure, ...
                                "Error: please enter a valid ROI name. Cannot be an empty string or a duplicate of an existing ROI's name.", 'Error')
                        else
                           
                            % Get new index
                            parentROIIndex = size(app.ROIsListBox.Items,2) + 1;
                            
                            % Add a new entry to the ROIs listbox and select it
                            app.roiData(end + 1).name = roiName{:};
                            app.roiData(end).subROIs = struct('obj', [], 'plane', [], 'refImage', []);
                            app.roiData(end).color = app.cm(app.currColorInd, :);
                            app.currColorInd = app.currColorInd + 1;
                            app.ROIsListBox.Items{end + 1} = app.roiData(end).name;
                            app.ROIsListBox.Value = app.roiData(end).name;
                            ROIsListBoxValueChanged(app);
                            
                            % Save
                            for j = 1:size(currPlaneNums, 2)
                                
                                % Create ROI object
                                sROI = images.roi.Freehand(app.axMaxZ, 'Color', cmap(i,:));
                                sX = x_coor(idx == i); 
                                sY = y_coor(idx == i);
                                k = boundary(sX,sY);
                                sROI.Position = [sX(k), sY(k)];
                                sROI.Color = cmap(i,:);
                                sROI.Label = roiName{:};
                                
                                % Sample way points to those at curvatures
                                fpos = sROI.Position;
                                dfpos = diff([fpos(end,:); fpos]); % Measure the rate of change. The neighbor of the first point is the last point.
                                cm = sum(abs(conv2(dfpos, ones(3,2),'same')),2);
                                [~, cmInds] = sort(cm);
                                numPointsToCull = round(0.25*size(fpos,1));
                                cpos = false(size(fpos, 1), 1);
                                cpos(cmInds(1:numPointsToCull)) = true;
                                sROI.Waypoints(:) = cpos;
                                
                                % Reduce
                                reduce(sROI); % --> Majorly reduce maypoint number
                                
                                % Assign planes
                                plane = currPlaneNums(j);
                                app.roiData(parentROIIndex).subROIs(j).obj = sROI;
                                app.roiData(parentROIIndex).subROIs(j).plane = plane; 
                            end
                        end    
                    end 
                end
                
                %% Refesh
                app.ROIsListBox.Value = app.ROIsListBox.Items;
                update_display(app)
                                                           
            else
                uialert(app.UIFigure, 'Error: please make sure you created a valid ROI!', 'Error')
            end                                              
                 
        end

        % Button pushed function: autoSegmentButton
        function autoButtonPushed(app, event)
            % Check plane and ROI list selection status
            targetPlaneStr = app.planeSelectDropdown.Value;
            targetPlane = regexp(targetPlaneStr, '(?<=Plane ).*', 'match', 'once');

            if isnumeric(str2double(targetPlane))
                
                % Grab handle to the appropriate axes
                for iPlot = 1:numel(app.refImageAxes)
                    if strcmp(app.refImageAxes{iPlot}.UserData, targetPlane)
                        targetAxes = app.refImageAxes{iPlot};
                    end
                end
                
                % Announce
                titleStr = targetAxes.Title.String;
                targetAxes.Title.String = '*** AUTOSEGMENTATION OF PLANE ***';
                
                % Prompt user to draw the new ROI
                plane = str2double(targetPlane);
                refImage = app.refImages{:};
                im = refImage(:,:,plane);
                maxIntensity = str2double(app.maxIntensityEditField.Value);
                minIntensity = str2double(app.minIntensityEditField.Value);
                im = rescale(im, minIntensity, maxIntensity);
                
                % User input
                prompt = {'Enter K of imsegkmeans clustering: ','Enter number of groups to preserve for ROIs :'};
                dlgtitle = 'plane auto-segmentation';
                dims = [1 35];
                definput = {'5','3'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                iK = str2double(answer{1});
                groups = str2double(answer{2});
                
                % Segment
                segmentedLabels = imsegkmeans(im,iK);
                mask = segmentedLabels==2;
                
                % Show results
                clear_plot_data(app);
                imshowpair(im, mask, 'Parent', targetAxes);
                pause(2);

                % Get X largest objects
                Mask = bwareafilt(mask, groups);
                clear_plot_data(app);
                imshowpair(im, Mask, 'Parent', targetAxes);
                                
                % Convert to freehadn object
                blocations = bwboundaries(Mask,'noholes');
                cmap = cbrewer2('set1',numel(blocations));         

                % User input
                targetAxes.Title.String = titleStr;
                opts.Interpreter = 'tex';
                opts.Default = 'Yes';
                quest = 'Create ROIs?';
                choice = questdlg(quest,'auto-segmentation', 'Yes','No',opts);
                if strcmp(choice,'Yes')
                    % Save each centroid shape
                    opts.Interpreter = 'tex';
                    majorName = inputdlg('\fontsize{12}Please enter a name for the new ROI cluster:', '', 1 , {''}, opts);
                    parentROIIndex = size(app.ROIsListBox.Items,2) + 1;
                    for i = 1:numel(blocations)
                        
                        % Convert to x,y order.
                        pos = blocations{i};
                        pos = fliplr(pos);
                    
                        % Create ROI object
                        sROI = images.roi.Freehand(app.axMaxZ, 'Color', cmap(i,:));
                        sROI.Position = pos;
                        sROI.Color = cmap(i,:);
                        
                        % Sample way points to those at curvatures
                        fpos = sROI.Position;
                        dfpos = diff([fpos(end,:); fpos]); % Measure the rate of change. The neighbor of the first point is the last point.
                        cm = sum(abs(conv2(dfpos, ones(3,2),'same')),2);
                        [~, cmInds] = sort(cm);
                        numPointsToCull = round(0.25*size(fpos,1));
                        cpos = false(size(fpos, 1), 1);
                        cpos(cmInds(1:numPointsToCull)) = true;
                        sROI.Waypoints(:) = cpos;
                        
                        % Reduce
                        reduce(sROI); % --> Majorly reduce maypoint number                       
                        
                        % Check name  
                        roiName = {[char(majorName) '_' num2str(i)]};
                        currROIs = app.ROIsListBox.Items;
                        if isempty(roiName)
                            app.msgLabel.Text = ' ROI creation cancelled';
                        elseif strcmpi(roiName{1}, '') || (~isempty(currROIs) && any(cellfun(@strcmp, currROIs, repmat(roiName, size(currROIs)))))
                            uialert(app.UIFigure, ...
                                "Error: please enter a valid ROI name. Cannot be an empty string or a duplicate of an existing ROI's name.", 'Error')
                        else
                            % Add a new entry to the ROIs listbox and select it
                            app.roiData(end + 1).name = roiName{:};
                            app.roiData(end).subROIs = struct('obj', [], 'plane', [], 'refImage', []);
                            app.roiData(end).color = app.cm(app.currColorInd, :);
                            app.currColorInd = app.currColorInd + 1;
                            app.ROIsListBox.Items{end + 1} = app.roiData(end).name;
                            app.ROIsListBox.Value = app.roiData(end).name;
                            ROIsListBoxValueChanged(app);
                            
                            % Save
                            app.roiData(parentROIIndex).subROIs(1).obj = sROI;
                            app.roiData(parentROIIndex).subROIs(1).plane = plane; 
                            parentROIIndex = parentROIIndex + 1;
                        end    
                            
                    end 
                end
                
                %% Refesh
                app.ROIsListBox.Value = app.ROIsListBox.Items;
                update_display(app)
 
            else
                uialert(app.UIFigure, 'Error: please make sure you have selected a valid plane and parent ROI!', 'Error')
            end
        end

        % Button pushed function: createROIButton_2
        function renameButtonPushed(app, event)
            %% Get parent ROI info                        
            parentROIIndex = strcmp(app.ROIsListBox.Items, app.selectedROIEditField.Value);
            parentROI = app.roiData(parentROIIndex);
            
            %% Select new name
            currentName = {parentROI.name};
            prompt = {'New ROI name:'};
            dlgtitle = 'plane auto-segmentation';
            dims = [1 35];
            definput = currentName;
            roiName = inputdlg(prompt,dlgtitle,dims,definput);
            
            %% Name not already in use
            currROIs = app.ROIsListBox.Items;
            if isempty(roiName)
                app.msgLabel.Text = ' ROI creation cancelled';
            elseif strcmpi(roiName{1}, '') || (~isempty(currROIs) && any(cellfun(@strcmp, currROIs, repmat(roiName, size(currROIs)))))
                uialert(app.UIFigure, ...
                    "Error: please enter a valid ROI name. Cannot be an empty string or a duplicate of an existing ROI's name.", 'Error')
            else
                roiName = roiName{:};
                app.roiData(parentROIIndex).name = roiName;
                
                %% Change in catalog
                app.ROIsListBox.Items{parentROIIndex} =roiName;
                app.ROIsListBox.Value = roiName;
                ROIsListBoxValueChanged(app);
                
                %% Rename data objects
                parentROI.name = roiName;
                for i = 1:size(parentROI.subROIs,2)
                  parentROI.subROIs(i).obj.Label = roiName;  
                end
                
            end
            
        end

        % Button pushed function: deleteROIButton_2
        function deletePlaneButtonPushed(app, event)
            %% Get parent ROI info
            logicalV = ismember(app.ROIsListBox.Items, app.ROIsListBox.Value);
            parentROIIndices = find(logicalV);
            
            % Check planes
            currPlaneNums = str2double(strip(app.planesListBox.Value));

            %% For each ROI selected, delete its values for this plane
            for parentROIIndex = parentROIIndices
                parentROI = app.roiData(parentROIIndex);
                % Delete plane
                for Plane = currPlaneNums
                     if isnumeric(Plane) && ~isempty(parentROI) && ~strcmpi(parentROI.name, '[select an ROI from the list]')
                        % Delete
                        if isempty(app.roiData(parentROIIndex).subROIs)
                            app.roiData(parentROIIndex).subROIs = [];
                            app.roiData(parentROIIndex).subROIs.obj = [];
                            app.roiData(parentROIIndex).subROIs.plane = [];
                            app.roiData(parentROIIndex).subROIs.refImage = [];
                            continue
                        else
                            keep = [app.roiData(parentROIIndex).subROIs.plane]~=Plane;
                            if ~sum(keep)
                                app.roiData(parentROIIndex).subROIs = [];
                                app.roiData(parentROIIndex).subROIs.obj = [];
                                app.roiData(parentROIIndex).subROIs.plane = [];
                                app.roiData(parentROIIndex).subROIs.refImage = [];
                            else
                               app.roiData(parentROIIndex).subROIs(~keep) = [];
                            end
                        end
                    end 
                end
            end
                        
            %% Refesh
            app.ROIsListBox.Value = app.ROIsListBox.Items;
            update_display(app)
            
        end

        % Button pushed function: processButton
        function processButtonPushed(app, event)
            folder = app.expDirEditField.Value;
            
            % User input
            prompt = {'trial: '};
            dlgtitle = 'ROI data process';
            dims = [1 35];
            definput = {'all'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
               return
            end
            trial = answer{:};
            if ~strcmp(trial, 'all')
                trial = pad(trial, 3, 'left', '0');
            end
            
            %% Run ROI pipeline
            app.msgLabel.Text = [' Processing ROIs for all trials .... may take a while ... '];
            pause(1)
            try
              imaging_roi_pipeline(folder, trial, 0, 0, 0, 0);
              app.msgLabel.Text = [' Processing ROIs for all trials ssaved in: ', folder, '/images ... '];
            catch ME
              uialert(app.UIFigure, 'Error: imaging_roi_pipeline failed! Did you save your ROIs?', 'Error')
              app.msgLabel.Text = sprintf('##### ERROR running imaging_roi_pipeline for: %s #####\n', folder);
              fprintf(1, '>>>>> %s <<<<<\n', ME.message);
            end
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            folder = app.expDirEditField.Value;
            trialNum = num2str(app.trialNumSpinner.Value);
            ch = regexprep(app.refImageChannel.Value,' ','');
            
            % User input
            prompt = {'trial: ', 'channel','roiGroup :', 'von mises group: '};
            dlgtitle = 'ROI data plot';
            dims = [1 35];
            definput = {trialNum,ch,'all', 'none'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
                return
            end
            nTrial = str2double(answer{1});
            channel = answer{2};
            roiGroup = answer{3};
            if strcmpi(roiGroup, 'all')
                roiGroup = '';
            end
            von_mises = answer{4};
            if strcmpi(von_mises, 'none')
                von_mises = '';
            end
            
            % Plot
            app.msgLabel.Text = [' Plotting ROI data ... '];
            pause(1)
            try
              roi_plot(folder, channel, nTrial, roiGroup, von_mises, 0)
              app.msgLabel.Text = [' ROIs plots saved in: ', folder, ' ... '];
            catch ME
              uialert(app.UIFigure, 'Error: please make sure you have hit the process button!', 'Error')
              app.msgLabel.Text = sprintf('##### ERROR running roi_plot for: %s #####\n', folder);
              fprintf(1, '>>>>> %s <<<<<\n', ME.message);
            end
        end

        % Button pushed function: refineButton
        function refineButtonPressed(app, event)
            %% Get parent ROI info                        
            parentROIIndex = strcmp(app.ROIsListBox.Items, app.selectedROIEditField.Value);
            if sum(sum(parentROIIndex))>1
               uialert(app.UIFigure, 'Error: please select one ROI on one plane!', 'Error')
               return 
            end
            parentROI = app.roiData(parentROIIndex);
            
            % Are we looking at one or more than one plane?
            currPlaneNums = str2double(strip(app.planesListBox.Value));
            sz = size(currPlaneNums,2);
            
            % Get correct image axes
            if sz>1 % is empty when looking at maxZ
                uialert(app.UIFigure, 'Error: please select only one plane!', 'Error')
                return
            elseif ~isempty(app.planeSelectDropdown.Value)
                % Check plane and ROI list selection status
                targetPlaneStr = app.planeSelectDropdown.Value;
                targetPlane = regexp(targetPlaneStr, '(?<=Plane ).*', 'match', 'once');
                targetPlane = str2double(targetPlane);
            else
                uialert(app.UIFigure, 'Error: please select at least one plane!', 'Error')
                return
            end
            
            %% Functions to edit ROI waypoints with mouse scroll
            function changeSampleDensity(hfh, evt)
                % Restore Position property of ROI.
                hfh.Position = hfh.UserData.fpos;
                % Change tolerance by a fixed amount based on the direction of the scroll.
                % This code changes the tolerance by 0.01 for every scroll increment.
                tol = hfh.UserData.tol + 0.01 * (evt.VerticalScrollCount);
                % Restrict the range of tolerance values to be from 0 to 0.15, which is the
                % useful range.
                tol = max(min(tol, 0.15), 0);
                % Call |reduce| with the specified tolerance.
                reduce(hfh,tol);
                hfh.UserData.tol = tol;
                % Update the ROI and turn all the points into waypoints to show the
                % density.
                hfh.Waypoints(:) = true;
            end
                              
          % Get ROI
          hfh = parentROI.subROIs(ismember([parentROI.subROIs.plane],targetPlane));  
          if(isempty(hfh))
              uialert(app.UIFigure, 'Error: please select at least one plane!', 'Error')
              return
          elseif size(hfh,2)>1
              uialert(app.UIFigure, 'Error: only works when there is a single sub ROI on one plane', 'Error')
              return    
          else
              % Grab handle to the appropriate axes
              targetAxes = app.refImageAxes{targetPlane};
                
              % Prompt user to draw the new ROI
              titleStr = targetAxes.Title.String;
              targetAxes.Title.String = '*** SCROLL TO CHANGE POINT DENSITY, PRESS LETTER KEY TO EXIT ***';
      
              % initialise
              hfh = hfh.obj;
              fpos = hfh.Position;
              hfh.Waypoints(:) = true;
              hfh.UserData.fpos = fpos;
              hfh.UserData.tol = 0;
              
              % Respond to mouse scroll
              h = app.UIFigure;
              h.WindowScrollWheelFcn = @(h, evt) changeSampleDensity(hfh, evt);
              waitFor = get(app.UIFigure,'CurrentCharacter');
              waitSignal = get(app.UIFigure,'CurrentCharacter');
              while strcmp(waitFor, waitSignal)
                  pause(1);
                  waitFor = get(app.UIFigure,'CurrentCharacter');
              end
              
              % Remvoe wheel scroll function
              h.WindowScrollWheelFcn = [];
              
              % Save finalized set of points.
              pos = hfh.Position;
              label = hfh.Label;
              
              % Delete and create a new Freehand ROI with the new |Position| value.
              delete(hfh);
              sROI = images.roi.Freehand(targetAxes, 'Color', parentROI.color, 'FaceAlpha', 0.05);
              sROI.Position = pos;
              sROI.Label = label;
              
              % Save
              app.roiData(parentROIIndex).subROIs(ismember([parentROI.subROIs.plane],targetPlane)).obj = [];
              app.roiData(parentROIIndex).subROIs(ismember([parentROI.subROIs.plane],targetPlane)).obj = sROI;                   
              targetAxes.Title.String = titleStr;
              
          end
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ROI creation GUI';
            app.experimentdirectoryLabel = uilabel(app.UIFigure);
            app.experimentdirectoryLabel.Text = 'experiment directory';
            app.expDirEditField = uieditfield(app.UIFigure, 'text');
            app.expDirEditField.Editable = 'off';
            app.loadRefImgButton = uibutton(app.UIFigure, 'push');
            app.loadRefImgButton.ButtonPushedFcn = createCallbackFcn(app, @loadRefImgButtonPushed, true);
            app.loadRefImgButton.Text = 'load reference images';
            app.trialnumberLabel = uilabel(app.UIFigure);
            app.trialnumberLabel.Text = 'trial number';
            app.trialNumSpinner = uispinner(app.UIFigure);
            app.trialNumSpinner.Limits = [1 Inf];
            app.trialNumSpinner.RoundFractionalValues = 'on';
            app.trialNumSpinner.ValueChangedFcn = createCallbackFcn(app, @trialNumSpinnerValueChanged, true);
            app.trialNumSpinner.Value = 1;
            app.maxintensityLabel = uilabel(app.UIFigure);
            app.maxintensityLabel.Text = 'max intensity';
            app.maxIntensityEditField = uieditfield(app.UIFigure, 'text');
            app.maxIntensityEditField.Value = '1';
            app.planesLabel = uilabel(app.UIFigure);
            app.planesLabel.Text = 'planes';
            app.planesListBox = uilistbox(app.UIFigure);
            app.planesListBox.Items = {};
            app.planesListBox.Multiselect = 'on';
            app.planesListBox.Value = {};
            app.updateDisplayButton = uibutton(app.UIFigure, 'push');
            app.updateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @updateDisplayButtonPushed, true);
            app.updateDisplayButton.Text = 'update';
            app.drawButton = uibutton(app.UIFigure, 'push');
            app.drawButton.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = 'draw a new sub-ROI';
            app.onLabel = uilabel(app.UIFigure);
            app.onLabel.Text = 'on';
            app.planeSelectDropdown = uidropdown(app.UIFigure);
            app.planeSelectDropdown.Items = {'Plane'};
            app.planeSelectDropdown.Value = 'Plane';
            app.andaddittoLabel = uilabel(app.UIFigure);
            app.andaddittoLabel.Text = 'and add it to';
            app.selectedROIEditField = uieditfield(app.UIFigure, 'text');
            app.selectedROIEditField.Value = '[select an ROI from the list]';
            app.createROIButton = uibutton(app.UIFigure, 'push');
            app.createROIButton.ButtonPushedFcn = createCallbackFcn(app, @createROIButtonPushed, true);
            app.createROIButton.Text = 'create new ROI';
            app.deleteROIButton = uibutton(app.UIFigure, 'push');
            app.deleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @deleteROIButtonPushed, true);
            app.deleteROIButton.Text = 'delete selected ROIs';
            app.saveROIsButton = uibutton(app.UIFigure, 'push');
            app.saveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @saveROIsButtonPushed, true);
            app.saveROIsButton.Text = {'save selected ROIs'; ''};
            app.plotPanel = uipanel(app.UIFigure);
            app.browseButton = uibutton(app.UIFigure, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.ROIsListBox = uilistbox(app.UIFigure);
            app.ROIsListBox.Items = {};
            app.ROIsListBox.Multiselect = 'on';
            app.ROIsListBox.ValueChangedFcn = createCallbackFcn(app, @ROIsListBoxValueChanged, true);
            app.ROIsListBox.Value = {};
            app.msgLabel = uilabel(app.UIFigure);
            app.msgLabel.Text = '  ';
            app.selectAllROIsButton = uibutton(app.UIFigure, 'push');
            app.selectAllROIsButton.ButtonPushedFcn = createCallbackFcn(app, @selectAllROIsButtonPushed, true);
            app.selectAllROIsButton.Text = 'Select all';
            app.selectNoROIsButton = uibutton(app.UIFigure, 'push');
            app.selectNoROIsButton.ButtonPushedFcn = createCallbackFcn(app, @selectNoROIsButtonPushed, true);
            app.selectNoROIsButton.Text = 'Select none';
            app.selectAllPlanesButton = uibutton(app.UIFigure, 'push');
            app.selectAllPlanesButton.ButtonPushedFcn = createCallbackFcn(app, @selectAllPlanesButtonPushed, true);
            app.selectAllPlanesButton.Text = 'select all';
            app.loadROIsButton = uibutton(app.UIFigure, 'push');
            app.loadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @loadROIsButtonPushed, true);
            app.loadROIsButton.Text = 'load ROIs ';
            app.upOnePlaneButton = uibutton(app.UIFigure, 'push');
            app.upOnePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @upOnePlaneButtonPushed, true);
            app.upOnePlaneButton.Text = 'shift up';
            app.downOnePlaneButton = uibutton(app.UIFigure, 'push');
            app.downOnePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @downOnePlaneButtonPushed, true);
            app.downOnePlaneButton.Text = 'shift down';
            app.RogueLabel = uilabel(app.UIFigure);
            app.RogueLabel.Text = 'ROIs';
            app.maxZButton = uibutton(app.UIFigure, 'push');
            app.maxZButton.ButtonPushedFcn = createCallbackFcn(app, @maxZButtonPushed, true);
            app.maxZButton.Text = {'show max Z for'; 'selected planes'};
            app.shiftROIUpButton = uibutton(app.UIFigure, 'push');
            app.shiftROIUpButton.ButtonPushedFcn = createCallbackFcn(app, @shiftROIUpButtonPushed, true);
            app.shiftROIUpButton.Text = 'shift ROI up';
            app.shiftROIDownButton = uibutton(app.UIFigure, 'push');
            app.shiftROIDownButton.ButtonPushedFcn = createCallbackFcn(app, @shiftROIDownButtonPushed, true);
            app.shiftROIDownButton.Text = 'shift ROI Down';
            app.lineButton = uibutton(app.UIFigure, 'push');
            app.lineButton.ButtonPushedFcn = createCallbackFcn(app, @lineButtonPushed, true);
            app.lineButton.Text = 'line';
            app.minintensityLabel = uilabel(app.UIFigure);
            app.minintensityLabel.Text = 'min intensity';
            app.minIntensityEditField = uieditfield(app.UIFigure, 'text');
            app.minIntensityEditField.Value = '0';
            app.pointButton = uibutton(app.UIFigure, 'push');
            app.pointButton.ButtonPushedFcn = createCallbackFcn(app, @pointButtonPushed, true);
            app.pointButton.Text = 'point';
            app.refImageChannel = uiswitch(app.UIFigure, 'slider');
            app.refImageChannel.Items = {'Ch 1', 'Ch 2'};
            app.refImageChannel.Value = 'Ch 1';
            app.segmentButton = uibutton(app.UIFigure, 'push');
            app.segmentButton.ButtonPushedFcn = createCallbackFcn(app, @segmentStructure, true);
            app.segmentButton.Text = 'segment';
            app.autoSegmentButton = uibutton(app.UIFigure, 'push');
            app.autoSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @autoButtonPushed, true);
            app.autoSegmentButton.Text = 'auto';
            app.deleteROIButton_2 = uibutton(app.UIFigure, 'push');
            app.deleteROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @deletePlaneButtonPushed, true);
            app.deleteROIButton_2.Text = 'delete ROI plane';
            app.createROIButton_2 = uibutton(app.UIFigure, 'push');
            app.createROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @renameButtonPushed, true);
            app.createROIButton_2.Text = 'rename ROI';
            app.processButton = uibutton(app.UIFigure, 'push');
            app.processButton.ButtonPushedFcn = createCallbackFcn(app, @processButtonPushed, true);
            app.processButton.Text = 'process';
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.updateDisplayButton_2 = uibutton(app.UIFigure, 'push');
            app.updateDisplayButton_2.Text = 'video';
            app.refineButton = uibutton(app.UIFigure, 'push');
            app.refineButton.ButtonPushedFcn = createCallbackFcn(app, @refineButtonPressed, true);
            app.refineButton.Text = 'refine';
            app.imgTypeSwitch = uidropdown(app.UIFigure);
            app.imgTypeSwitch.Items = {'registered volume', 'deepcad volume', 'raw volume'};
            app.imgTypeSwitch.Value = 'registered volume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flys

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end